classdef Roll_Dice_Animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        RollButton  matlab.ui.control.Button
        DiceImage   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function Player1HitButtonPushed(app, event)
            
        
        end

        % Callback function: not associated with a component
        function Player2HitButtonPushed(app, event)
   
        end

        % Callback function: not associated with a component
        function Player1HoldButtonPushed(app, event)
                        app.Player1HitButton.Enable = "off"
        end

        % Callback function: not associated with a component
        function Player2HoldButtonPushed(app, event)
                        app.Player2HitButton.Enable = "off"
        end

        % Callback function: not associated with a component
        function ResetButtonPushed(app, event)
            Roll_Dice_Game()
            close(app.UIFigure)
        end

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [x,fs]=audioread('2853.wav');
            sound(x,fs);
            app.DiceImage.ImageSource=fullfile(pathToMLAPP,'dicet.png');
            pause(0.2);
            app.DiceImage.ImageSource = fullfile(pathToMLAPP,'dices.png');
            pause(0.2);
            app.DiceImage.ImageSource = fullfile(pathToMLAPP,'dicee.png');
            
            rollnum = randi(6);
            app.DiceImage.ImageSource=fullfile(pathToMLAPP,['dice',num2str(rollnum),'.png']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiceImage = uiimage(app.UIFigure);
            app.DiceImage.ImageSource = fullfile(pathToMLAPP, 'dice1.png');
            app.RollButton = uibutton(app.UIFigure, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Roll_Dice_Animation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end