classdef Roll_Dice_Game0_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DiceImage2         matlab.ui.control.Image
        DiceImage1         matlab.ui.control.Image
        Player2HoldButton  matlab.ui.control.Button
        Player2HitButton   matlab.ui.control.Button
        Player2Score       matlab.ui.control.NumericEditField
        Player2Gauge       matlab.ui.control.SemicircularGauge
        Player2Name        matlab.ui.control.Label
        Player1HitButton   matlab.ui.control.Button
        Player1Gauge       matlab.ui.control.SemicircularGauge
        Player1HoldButton  matlab.ui.control.Button
        Player1Score       matlab.ui.control.NumericEditField
        Player1Name        matlab.ui.control.Label
        ResetButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Player1HitButton
        function Player1HitButtonPushed(app, event)
            %replace gauge with dice animation
            app.Player1Gauge.Visible ="off";
            app.DiceImage1.Visible ="on";
            %execute dice animation
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [x,fs]=audioread('2853.wav');
            sound(x,fs);
            app.DiceImage1.ImageSource=fullfile(pathToMLAPP,'dicet.png');
            pause(0.2);
            app.DiceImage1.ImageSource = fullfile(pathToMLAPP,'dices.png');
            pause(0.2);
            app.DiceImage1.ImageSource = fullfile(pathToMLAPP,'dicee.png');
            
            rollnum = randi(6);
            app.DiceImage1.ImageSource=fullfile(pathToMLAPP,['dice',num2str(rollnum),'.png']);
            %swap dice image back to gague
            pause(2); %dramatic pause...!
            app.DiceImage1.Visible ="off";
            app.Player1Gauge.Visible ="on";
            %proceed with the game
            Roll = rollnum;
                app.Player1Gauge.Value = app.Player1Gauge.Value + Roll;

                app.Player1Score.Value = app.Player1Gauge.Value;

                        if app.Player1Gauge.Value > 12

                            app.Player1Gauge.BackgroundColor = 'Red'; %Turns Colour Red for "bust"
        
                            app.Player1Gauge.Value = 0;
        
                            app.Player1HitButton.Enable = 'off';       %finishes turn when "bust"
    
                        elseif  app.Player1Gauge.Value == 12
        
                            app.Player1Gauge.BackgroundColor = 'Green'; %Turns Colour Green for "12"
        
                            app.Player1HitButton.Enable = 'off'
                end
        end

        % Button pushed function: Player2HitButton
        function Player2HitButtonPushed(app, event)
               %replace gauge with dice animation
            app.Player2Gauge.Visible ="off";
            app.DiceImage2.Visible ="on";
            %execute dice animation
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [x,fs]=audioread('2853.wav');
            sound(x,fs);
            app.DiceImage2.ImageSource=fullfile(pathToMLAPP,'dicet.png');
            pause(0.2);
            app.DiceImage2.ImageSource = fullfile(pathToMLAPP,'dices.png');
            pause(0.2);
            app.DiceImage2.ImageSource = fullfile(pathToMLAPP,'dicee.png');
            
            rollnum = randi(6);
            app.DiceImage2.ImageSource=fullfile(pathToMLAPP,['dice',num2str(rollnum),'.png']);
            %swap dice image back to gague
            pause(2); %dramatic pause...!
            app.DiceImage2.Visible ="off";
            app.Player2Gauge.Visible ="on";
            %proceed with the game
            Roll = rollnum;        
                app.Player2Gauge.Value = app.Player2Gauge.Value + Roll;
                
                app.Player2Score.Value = app.Player2Gauge.Value;

                        if app.Player2Gauge.Value > 12
    
                            app.Player2Gauge.BackgroundColor = 'Red'; %Turns Colour Red for "bust"
        
                            app.Player2Gauge.Value = 0;
        
                            app.Player2HitButton.Enable = 'off'; %finishes turn when "bust"
        
                        elseif app.Player2Gauge.Value == 12
        
                            app.Player2Gauge.BackgroundColor = 'Green'; %Turns Colour Green for "12"

                            app.Player2HitButton.Enable = 'off'
                end
        end

        % Button pushed function: Player1HoldButton
        function Player1HoldButtonPushed(app, event)
                        app.Player1HitButton.Enable = "off"
        end

        % Button pushed function: Player2HoldButton
        function Player2HoldButtonPushed(app, event)
                        app.Player2HitButton.Enable = "off"
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            Roll_Dice_Game()
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Player1Name = uilabel(app.UIFigure);
            app.Player1Name.Text = 'Player 1';
            app.Player1Score = uieditfield(app.UIFigure, 'numeric');
            app.Player1Score.Editable = 'off';
            app.Player1HoldButton = uibutton(app.UIFigure, 'push');
            app.Player1HoldButton.ButtonPushedFcn = createCallbackFcn(app, @Player1HoldButtonPushed, true);
            app.Player1HoldButton.Text = 'Hold';
            app.Player1Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Player1Gauge.Limits = [0 12];
            app.Player1Gauge.MajorTicks = [0 6 12];
            app.Player1HitButton = uibutton(app.UIFigure, 'push');
            app.Player1HitButton.ButtonPushedFcn = createCallbackFcn(app, @Player1HitButtonPushed, true);
            app.Player1HitButton.Text = 'Roll Dice';
            app.Player2Name = uilabel(app.UIFigure);
            app.Player2Name.Text = 'Player 2';
            app.Player2Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Player2Gauge.Limits = [0 12];
            app.Player2Gauge.MajorTicks = [0 6 12];
            app.Player2Score = uieditfield(app.UIFigure, 'numeric');
            app.Player2Score.Editable = 'off';
            app.Player2HitButton = uibutton(app.UIFigure, 'push');
            app.Player2HitButton.ButtonPushedFcn = createCallbackFcn(app, @Player2HitButtonPushed, true);
            app.Player2HitButton.Text = 'Roll Dice';
            app.Player2HoldButton = uibutton(app.UIFigure, 'push');
            app.Player2HoldButton.ButtonPushedFcn = createCallbackFcn(app, @Player2HoldButtonPushed, true);
            app.Player2HoldButton.Text = 'Hold';
            app.DiceImage1 = uiimage(app.UIFigure);
            app.DiceImage1.Visible = 'off';
            app.DiceImage2 = uiimage(app.UIFigure);
            app.DiceImage2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Roll_Dice_Game0_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end