classdef Roll_Dice_GameV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Player1Screen          matlab.ui.container.Panel
        Player2Num             matlab.ui.control.NumericEditField
        DiceImage1             matlab.ui.control.Image
        Player1table           matlab.ui.control.Table
        Player1HoldButton      matlab.ui.control.Button
        Player1HitButton       matlab.ui.control.Button
        Player1Score           matlab.ui.control.NumericEditField
        Player1Name            matlab.ui.control.EditField
        Player1Gauge           matlab.ui.control.SemicircularGauge
        ResetButtonP1          matlab.ui.control.Button
        Player2Screen          matlab.ui.container.Panel
        Player1Num             matlab.ui.control.NumericEditField
        DiceImage2             matlab.ui.control.Image
        Player2table           matlab.ui.control.Table
        Player2Name            matlab.ui.control.EditField
        Player2HoldButton      matlab.ui.control.Button
        Player2HitButton       matlab.ui.control.Button
        Player2Score           matlab.ui.control.NumericEditField
        Player2Gauge           matlab.ui.control.SemicircularGauge
        ResetButtonP2          matlab.ui.control.Button
        FinalScreen            matlab.ui.container.Panel
        Player2finaltable      matlab.ui.control.Table
        Player1finaltable      matlab.ui.control.Table
        NextRoundButton        matlab.ui.control.Button
        ResetButtonP1_2        matlab.ui.control.Button
        Player2Name_2          matlab.ui.control.EditField
        Player1Name_2          matlab.ui.control.EditField
        Player2FinalScore      matlab.ui.control.NumericEditField
        Player1FinalScore      matlab.ui.control.NumericEditField
        FINALSCORESLabel       matlab.ui.control.Label
        StartScreen            matlab.ui.container.Panel
        Player2Namer           matlab.ui.control.EditField
        Player2EditFieldLabel  matlab.ui.control.Label
        Player1Namer           matlab.ui.control.EditField
        Player1EditFieldLabel  matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Player1Screen.Visible = "off";
           app.Player2Screen.Visible = "off";
           app.FinalScreen.Visible = "off";
        end

        % Button pushed function: Player1HitButton
        function Player1HitButtonPushed(app, event)
            app.DiceImage1.Visible ="on";
            app.Player1HitButton.Enable = "off"
            %execute dice animation
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [x,fs]=audioread('2853.wav');
            sound(x,fs);
            app.DiceImage1.ImageSource=fullfile(pathToMLAPP,'dicet.png');
            pause(0.2);
            app.DiceImage1.ImageSource = fullfile(pathToMLAPP,'dices.png');
            pause(0.2);
            app.DiceImage1.ImageSource = fullfile(pathToMLAPP,'dicee.png');
            
            rollnum = randi(6);
            app.DiceImage1.ImageSource=fullfile(pathToMLAPP,['dice',num2str(rollnum),'.png']);
            %swap dice image back to gague

            Roll = rollnum; 
            
                app.Player1Gauge.Value = app.Player1Gauge.Value + Roll;

                app.Player1Score.Value = app.Player1Gauge.Value;
                app.Player1table.Data = [app.Player1table.Data; Roll];
               app.Player1Num.Value = app.Player1Score.Value
               pause(2)
         app.Player1HitButton.Enable = "on"

                        if app.Player1Gauge.Value > 12
                            
                            app.Player1Gauge.BackgroundColor = 'Red'; %Turns Colour Red for "bust"
        
                            app.Player1Gauge.Value = 0;
        
                            app.Player1HitButton.Enable = "off";       %finishes turn when "bust"
                     
            app.Player1finaltable.Data = [app.Player1finaltable.Data; 0]


                        elseif  app.Player1Gauge.Value == 12
        
                            app.Player1Gauge.BackgroundColor = 'Green'; %Turns Colour Green for "12"
        
                            app.Player1HitButton.Enable = "off";

            app.Player1finaltable.Data = [app.Player1finaltable.Data; app.Player1Score.Value]
               
                        end
                        app.Player1FinalScore.Value = app.Player1Score.Value;

                    if app.Player2HitButton.Enable == "off"
                        if app.Player1HitButton.Enable == "on"
                                 app.Player1Screen.Visible = "on"
                        else 
                            
                            app.Player1Screen.Visible = "off"
                            app.FinalScreen.Visible = "on"
                        end
                    else
                            
                               app.Player1Screen.Visible = "off"
                                app.Player2Screen.Visible = "on"

                            end
                  

        end

        % Button pushed function: Player2HitButton
        function Player2HitButtonPushed(app, event)
                                 %replace gauge with dice animation

            app.DiceImage2.Visible ="on";
            app.Player2HitButton.Enable = "off"
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [x,fs]=audioread('2853.wav');
            sound(x,fs);
            app.DiceImage2.ImageSource=fullfile(pathToMLAPP,'dicet.png');
            pause(0.2);
            app.DiceImage2.ImageSource = fullfile(pathToMLAPP,'dices.png');
            pause(0.2);
            app.DiceImage2.ImageSource = fullfile(pathToMLAPP,'dicee.png');
            
            rollnum = randi(6);
            app.DiceImage2.ImageSource=fullfile(pathToMLAPP,['dice',num2str(rollnum),'.png']);
                
            Roll = rollnum
                app.Player2Gauge.Value = app.Player2Gauge.Value + Roll;
                
                app.Player2Score.Value = app.Player2Gauge.Value;
                app.Player2table.Data = [app.Player2table.Data; Roll];
                app.Player2Num.Value = app.Player2Score.Value
            pause(2)
                            app.Player2HitButton.Enable = "on"
                        if app.Player2Gauge.Value > 12
    
                            app.Player2Gauge.BackgroundColor = 'Red'; %Turns Colour Red for "bust"
        
                            app.Player2Gauge.Value = 0;
        
                            app.Player2HitButton.Enable = "off"; %finishes turn when "bust"

                         app.Player2finaltable.Data = [app.Player2finaltable.Data; 0]
                   

                        elseif app.Player2Gauge.Value == 12
        
                            app.Player2Gauge.BackgroundColor = 'Green'; %Turns Colour Green for "12"

                            app.Player2HitButton.Enable = "off"

            app.Player2finaltable.Data = [app.Player2finaltable.Data; app.Player2Score.Value]
            
                       
                        end
                        app.Player2FinalScore.Value = app.Player2Score.Value;
                        

            if app.Player1HitButton.Enable == "off"
                        if app.Player2HitButton.Enable == "on"
                                 app.Player2Screen.Visible = "on"
                        else 
                            
                            app.Player2Screen.Visible = "off"
                            app.FinalScreen.Visible = "on"
                        end
                    else
                             
                               app.Player2Screen.Visible = "off"
                                app.Player1Screen.Visible = "on"

            end
            
        end

        % Button pushed function: Player1HoldButton
        function Player1HoldButtonPushed(app, event)
                        app.Player1HitButton.Enable = "off"
                         app.Player1finaltable.Data = [app.Player1finaltable.Data; app.Player1Score.Value]
            app.Player2Screen.Visible = "on"
            app.Player1Screen.Visible = "off"
        end

        % Button pushed function: Player2HoldButton
        function Player2HoldButtonPushed(app, event)
                        app.Player2HitButton.Enable = "off"
                         app.Player2finaltable.Data = [app.Player2finaltable.Data; app.Player2Score.Value]
            app.FinalScreen.Visible = "on"
            app.Player2Screen.Visible = "off"
        end

        % Button pushed function: ResetButtonP1, ResetButtonP1_2, 
        % ...and 1 other component
        function ResetButtonPushed(app, event)
            Roll_Dice_GameV3()
            close(app.UIFigure)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Player1Screen.Visible = "on"
            app.StartScreen.Visible = "off"
        end

        % Value changed function: Player1Namer
        function Player1NamerValueChanged(app, event)
            value = app.Player1Namer.Value;
            app.Player1Name.Value = value;
            app.Player1Name_2.Value = value
        end

        % Value changed function: Player2Namer
        function Player2NamerValueChanged(app, event)
            value = app.Player2Namer.Value;
             app.Player2Name.Value = value;
              app.Player2Name_2.Value = value;
        end

        % Cell edit callback: Player2table
        function Player2tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: NextRoundButton
        function NextRoundButtonPushed(app, event)
            app.Player1Score.Value = 0
            app.Player1Gauge.Value = 0
            app.Player1table.Data = []
            app.Player1Num.Value = 0
            app.Player1Gauge.BackgroundColor = 'white'
            

            app.Player2Score.Value = 0
            app.Player2Gauge.Value = 0
            app.Player2table.Data = []
            app.Player2Num.Value = 0
            app.Player2Gauge.BackgroundColor = 'white'

            app.Player1HitButton.Enable = "on"
            app.Player2HitButton.Enable = "on"
            app.Player1Screen.Visible = "on"
            app.FinalScreen.Visible = "off"

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartScreen = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.StartScreen, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Player1EditFieldLabel = uilabel(app.StartScreen);
            app.Player1EditFieldLabel.Text = 'Player 1';
            app.Player1Namer = uieditfield(app.StartScreen, 'text');
            app.Player1Namer.ValueChangedFcn = createCallbackFcn(app, @Player1NamerValueChanged, true);
            app.Player2EditFieldLabel = uilabel(app.StartScreen);
            app.Player2EditFieldLabel.Text = 'Player 2';
            app.Player2Namer = uieditfield(app.StartScreen, 'text');
            app.Player2Namer.ValueChangedFcn = createCallbackFcn(app, @Player2NamerValueChanged, true);
            app.FinalScreen = uipanel(app.UIFigure);
            app.FINALSCORESLabel = uilabel(app.FinalScreen);
            app.FINALSCORESLabel.Text = 'FINAL SCORES';
            app.Player1FinalScore = uieditfield(app.FinalScreen, 'numeric');
            app.Player1FinalScore.Editable = 'off';
            app.Player2FinalScore = uieditfield(app.FinalScreen, 'numeric');
            app.Player2FinalScore.Editable = 'off';
            app.Player1Name_2 = uieditfield(app.FinalScreen, 'text');
            app.Player1Name_2.Editable = 'off';
            app.Player2Name_2 = uieditfield(app.FinalScreen, 'text');
            app.Player2Name_2.Editable = 'off';
            app.ResetButtonP1_2 = uibutton(app.FinalScreen, 'push');
            app.ResetButtonP1_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButtonP1_2.Text = 'Reset';
            app.NextRoundButton = uibutton(app.FinalScreen, 'push');
            app.NextRoundButton.ButtonPushedFcn = createCallbackFcn(app, @NextRoundButtonPushed, true);
            app.NextRoundButton.Text = 'NextRound';
            app.Player1finaltable = uitable(app.FinalScreen);
            app.Player1finaltable.ColumnName = {'Rolls'};
            app.Player1finaltable.RowName = {};
            app.Player1finaltable.Multiselect = 'off';
            app.Player2finaltable = uitable(app.FinalScreen);
            app.Player2finaltable.ColumnName = {'Rolls'};
            app.Player2finaltable.RowName = {};
            app.Player2finaltable.Multiselect = 'off';
            app.Player2Screen = uipanel(app.UIFigure);
            app.Player2Screen.Title = 'Player2Screen';
            app.ResetButtonP2 = uibutton(app.Player2Screen, 'push');
            app.ResetButtonP2.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButtonP2.Text = 'Reset';
            app.Player2Gauge = uigauge(app.Player2Screen, 'semicircular');
            app.Player2Gauge.Limits = [0 12];
            app.Player2Gauge.MajorTicks = [0 6 12];
            app.Player2Score = uieditfield(app.Player2Screen, 'numeric');
            app.Player2Score.Editable = 'off';
            app.Player2HitButton = uibutton(app.Player2Screen, 'push');
            app.Player2HitButton.ButtonPushedFcn = createCallbackFcn(app, @Player2HitButtonPushed, true);
            app.Player2HitButton.Text = 'Roll Dice';
            app.Player2HoldButton = uibutton(app.Player2Screen, 'push');
            app.Player2HoldButton.ButtonPushedFcn = createCallbackFcn(app, @Player2HoldButtonPushed, true);
            app.Player2HoldButton.Text = 'Hold';
            app.Player2Name = uieditfield(app.Player2Screen, 'text');
            app.Player2Name.Editable = 'off';
            app.Player2table = uitable(app.Player2Screen);
            app.Player2table.ColumnName = {'Rolls'};
            app.Player2table.RowName = {};
            app.Player2table.CellEditCallback = createCallbackFcn(app, @Player2tableCellEdit, true);
            app.Player2table.Multiselect = 'off';
            app.DiceImage2 = uiimage(app.Player2Screen);
            app.DiceImage2.Visible = 'off';
            app.Player1Num = uieditfield(app.Player2Screen, 'numeric');
            app.Player1Screen = uipanel(app.UIFigure);
            app.Player1Screen.Title = 'Player1Screen';
            app.ResetButtonP1 = uibutton(app.Player1Screen, 'push');
            app.ResetButtonP1.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButtonP1.Text = 'Reset';
            app.Player1Gauge = uigauge(app.Player1Screen, 'semicircular');
            app.Player1Gauge.Limits = [0 12];
            app.Player1Gauge.MajorTicks = [0 6 12];
            app.Player1Name = uieditfield(app.Player1Screen, 'text');
            app.Player1Name.Editable = 'off';
            app.Player1Score = uieditfield(app.Player1Screen, 'numeric');
            app.Player1Score.Editable = 'off';
            app.Player1HitButton = uibutton(app.Player1Screen, 'push');
            app.Player1HitButton.ButtonPushedFcn = createCallbackFcn(app, @Player1HitButtonPushed, true);
            app.Player1HitButton.Text = 'Roll Dice';
            app.Player1HoldButton = uibutton(app.Player1Screen, 'push');
            app.Player1HoldButton.ButtonPushedFcn = createCallbackFcn(app, @Player1HoldButtonPushed, true);
            app.Player1HoldButton.Text = 'Hold';
            app.Player1table = uitable(app.Player1Screen);
            app.Player1table.ColumnName = {'Rolls'};
            app.Player1table.RowName = {};
            app.Player1table.Multiselect = 'off';
            app.DiceImage1 = uiimage(app.Player1Screen);
            app.DiceImage1.Visible = 'off';
            app.Player2Num = uieditfield(app.Player1Screen, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Roll_Dice_GameV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end