classdef Roll_Dice_name < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        initFlagLabel              matlab.ui.control.Label
        nameInputPanel1            matlab.ui.container.Panel
        nameInputSumitBtn1         matlab.ui.control.Button
        nameInputCancelBtn1        matlab.ui.control.Button
        nameInputEdit1             matlab.ui.control.EditField
        Player1whatsyournameEditFieldLabel  matlab.ui.control.Label
        nameInputPanel2            matlab.ui.container.Panel
        nameInputSumitBtn2         matlab.ui.control.Button
        nameInputCancelBtn2        matlab.ui.control.Button
        nameInputEdit2             matlab.ui.control.EditField
        Player1whatsyournameLabel  matlab.ui.control.Label
        Player2HoldButton          matlab.ui.control.Button
        Player2HitButton           matlab.ui.control.Button
        Player2Score               matlab.ui.control.NumericEditField
        Player2Gauge               matlab.ui.control.SemicircularGauge
        modifyButton2              matlab.ui.control.Button
        Player2Name                matlab.ui.control.Label
        Player1HitButton           matlab.ui.control.Button
        Player1Gauge               matlab.ui.control.SemicircularGauge
        Player1HoldButton          matlab.ui.control.Button
        Player1Score               matlab.ui.control.NumericEditField
        modifyButton1              matlab.ui.control.Button
        Player1Name                matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Player1HitButton
        function Player1HitButtonPushed(app, event)
            
            Roll = randi(6);
                app.Player1Gauge.Value = app.Player1Gauge.Value + Roll;

                app.Player1Score.Value = app.Player1Gauge.Value;

                        if app.Player1Gauge.Value > 12

                            app.Player1Gauge.BackgroundColor = 'Red'; %Turns Colour Red for "bust"
        
                            app.Player1Gauge.Value = 0;
        
                            app.Player1HitButton.Enable = 'off';       %finishes turn when "bust"
    
                        elseif  app.Player1Gauge.Value == 12
        
                            app.Player1Gauge.BackgroundColor = 'Green'; %Turns Colour Green for "12"
        
                            app.Player1HitButton.Enable = 'off'
                end
        end

        % Button pushed function: Player2HitButton
        function Player2HitButtonPushed(app, event)
                       
            Roll = randi(6);
                app.Player2Gauge.Value = app.Player2Gauge.Value + Roll;
                
                app.Player2Score.Value = app.Player2Gauge.Value;

                        if app.Player2Gauge.Value > 12
    
                            app.Player2Gauge.BackgroundColor = 'Red'; %Turns Colour Red for "bust"
        
                            app.Player2Gauge.Value = 0;
        
                            app.Player2HitButton.Enable = 'off'; %finishes turn when "bust"
        
                        elseif app.Player2Gauge.Value == 12
        
                            app.Player2Gauge.BackgroundColor = 'Green'; %Turns Colour Green for "12"

                            app.Player2HitButton.Enable = 'off'
                end
        end

        % Button pushed function: Player1HoldButton
        function Player1HoldButtonPushed(app, event)
                        app.Player1HitButton.Enable = "off"
        end

        % Button pushed function: Player2HoldButton
        function Player2HoldButtonPushed(app, event)
                        app.Player2HitButton.Enable = "off"
        end

        % Callback function: not associated with a component
        function ResetButtonPushed(app, event)
            Roll_Dice_Game()
            close(app.UIFigure)
        end

        % Callback function: not associated with a component
        function RollButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [x,fs]=audioread('2853.wav');
            sound(x,fs);

            app.DiceImage.ImageSource=fullfile(pathToMLAPP,'dicet.png');
            pause(0.2);
            app.DiceImage.ImageSource = fullfile(pathToMLAPP,'dices.png');
            pause(0.2);
            app.DiceImage.ImageSource = fullfile(pathToMLAPP,'dicee.png');            
            rollnum = randi(6);
            app.DiceImage.ImageSource=fullfile(pathToMLAPP,['dice',num2str(rollnum),'.png']);
        end

        % Button pushed function: nameInputSumitBtn1
        function nameInputSumitBtn1ButtonPushed(app, event)
            play1name=app.nameInputEdit1.Value;            
            app.Player1Name.Text = play1name;
            set(app.nameInputPanel1,'visible','off');
            if(app.initFlagLabel.Text=='0')
               set(app.nameInputPanel2,'visible','on');
            end
            app.initFlagLabel.Text='1';
        end

        % Button pushed function: nameInputCancelBtn1
        function nameInputCancelBtn1ButtonPushed(app, event)
            set(app.nameInputPanel1,'visible','off');
            if(app.initFlagLabel.Text=='0')
               set(app.nameInputPanel2,'visible','on');
            end
        end

        % Button pushed function: nameInputSumitBtn2
        function nameInputSumitBtn2ButtonPushed(app, event)
            
            play2name=app.nameInputEdit2.Value;
            app.Player2Name.Text = play2name;
            set(app.nameInputPanel2,'visible','off');
        end

        % Button pushed function: nameInputCancelBtn2
        function nameInputCancelBtn2ButtonPushed(app, event)
            set(app.nameInputPanel2,'visible','off');
        end

        % Button pushed function: modifyButton1
        function modifyButton1Pushed(app, event)
            set(app.nameInputPanel1,'visible','on');
            set(app.nameInputPanel2,'visible','off');
        end

        % Button pushed function: modifyButton2
        function modifyButton2Pushed(app, event)
            set(app.nameInputPanel2,'visible','on');
            set(app.nameInputPanel1,'visible','off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Player1Name = uilabel(app.UIFigure);
            app.Player1Name.Text = 'Player 1';
            app.modifyButton1 = uibutton(app.UIFigure, 'push');
            app.modifyButton1.ButtonPushedFcn = createCallbackFcn(app, @modifyButton1Pushed, true);
            app.modifyButton1.Text = 'modify';
            app.Player1Score = uieditfield(app.UIFigure, 'numeric');
            app.Player1Score.Editable = 'off';
            app.Player1HoldButton = uibutton(app.UIFigure, 'push');
            app.Player1HoldButton.ButtonPushedFcn = createCallbackFcn(app, @Player1HoldButtonPushed, true);
            app.Player1HoldButton.Text = 'Hold';
            app.Player1Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Player1Gauge.Limits = [0 12];
            app.Player1Gauge.MajorTicks = [0 6 12];
            app.Player1HitButton = uibutton(app.UIFigure, 'push');
            app.Player1HitButton.ButtonPushedFcn = createCallbackFcn(app, @Player1HitButtonPushed, true);
            app.Player1HitButton.Text = 'Roll Dice';
            app.Player2Name = uilabel(app.UIFigure);
            app.Player2Name.Text = 'Player 2';
            app.modifyButton2 = uibutton(app.UIFigure, 'push');
            app.modifyButton2.ButtonPushedFcn = createCallbackFcn(app, @modifyButton2Pushed, true);
            app.modifyButton2.Text = 'modify';
            app.Player2Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Player2Gauge.Limits = [0 12];
            app.Player2Gauge.MajorTicks = [0 6 12];
            app.Player2Score = uieditfield(app.UIFigure, 'numeric');
            app.Player2Score.Editable = 'off';
            app.Player2HitButton = uibutton(app.UIFigure, 'push');
            app.Player2HitButton.ButtonPushedFcn = createCallbackFcn(app, @Player2HitButtonPushed, true);
            app.Player2HitButton.Text = 'Roll Dice';
            app.Player2HoldButton = uibutton(app.UIFigure, 'push');
            app.Player2HoldButton.ButtonPushedFcn = createCallbackFcn(app, @Player2HoldButtonPushed, true);
            app.Player2HoldButton.Text = 'Hold';
            app.nameInputPanel2 = uipanel(app.UIFigure);
            app.nameInputPanel2.Title = 'InputName';
            app.nameInputPanel2.Visible = 'off';
            app.Player1whatsyournameLabel = uilabel(app.nameInputPanel2);
            app.Player1whatsyournameLabel.Text = 'Player2 whats your name?';
            app.nameInputEdit2 = uieditfield(app.nameInputPanel2, 'text');
            app.nameInputCancelBtn2 = uibutton(app.nameInputPanel2, 'push');
            app.nameInputCancelBtn2.ButtonPushedFcn = createCallbackFcn(app, @nameInputCancelBtn2ButtonPushed, true);
            app.nameInputCancelBtn2.Text = 'Cancel';
            app.nameInputSumitBtn2 = uibutton(app.nameInputPanel2, 'push');
            app.nameInputSumitBtn2.ButtonPushedFcn = createCallbackFcn(app, @nameInputSumitBtn2ButtonPushed, true);
            app.nameInputSumitBtn2.Text = 'Sumit';
            app.nameInputPanel1 = uipanel(app.UIFigure);
            app.nameInputPanel1.Title = 'InputName';
            app.Player1whatsyournameEditFieldLabel = uilabel(app.nameInputPanel1);
            app.Player1whatsyournameEditFieldLabel.Text = 'Player1 what''s your name?';
            app.nameInputEdit1 = uieditfield(app.nameInputPanel1, 'text');
            app.nameInputEdit1.Value = 'will';
            app.nameInputCancelBtn1 = uibutton(app.nameInputPanel1, 'push');
            app.nameInputCancelBtn1.ButtonPushedFcn = createCallbackFcn(app, @nameInputCancelBtn1ButtonPushed, true);
            app.nameInputCancelBtn1.Text = 'Cancel';
            app.nameInputSumitBtn1 = uibutton(app.nameInputPanel1, 'push');
            app.nameInputSumitBtn1.ButtonPushedFcn = createCallbackFcn(app, @nameInputSumitBtn1ButtonPushed, true);
            app.nameInputSumitBtn1.Text = 'Sumit';
            app.initFlagLabel = uilabel(app.UIFigure);
            app.initFlagLabel.Enable = 'off';
            app.initFlagLabel.Visible = 'off';
            app.initFlagLabel.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Roll_Dice_name

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end